classdef img_test_2019 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        FilesTab                   matlab.ui.container.Tab
        FilesListBoxLabel          matlab.ui.control.Label
        FilesListBox               matlab.ui.control.ListBox
        SetRootButton              matlab.ui.control.Button
        OverviewLabel              matlab.ui.control.Label
        FindMissingFilesButton     matlab.ui.control.Button
        OpeninExplorerButton       matlab.ui.control.Button
        ThumbSwitchLabel           matlab.ui.control.Label
        ThumbSwitch                matlab.ui.control.RockerSwitch
        ThumbImage                 matlab.ui.control.Image
        ProcessAllButton           matlab.ui.control.Button
        ROISourceDropDownLabel     matlab.ui.control.Label
        ROISourceDropDown          matlab.ui.control.DropDown
        SignalChannelsButtonGroup  matlab.ui.container.ButtonGroup
        SingleChannelButton        matlab.ui.control.RadioButton
        DoubleChannelButton        matlab.ui.control.RadioButton
        TripleChannelButton        matlab.ui.control.RadioButton
        RhSigChnSpinnerLabel       matlab.ui.control.Label
        RhSigChnSpinner            matlab.ui.control.Spinner
        Cy5SigChnSpinnerLabel      matlab.ui.control.Label
        Cy5SigChnSpinner           matlab.ui.control.Spinner
        SigNumChnsSpinnerLabel     matlab.ui.control.Label
        SigNumChnsSpinner          matlab.ui.control.Spinner
        ROINumChnsSpinnerLabel     matlab.ui.control.Label
        ROINumChnsSpinner          matlab.ui.control.Spinner
        ROIChnSpinnerLabel         matlab.ui.control.Label
        ROIChnSpinner              matlab.ui.control.Spinner
        UseMasksDropDownLabel      matlab.ui.control.Label
        UseMasksDropDown           matlab.ui.control.DropDown
        maskstatusLabel            matlab.ui.control.Label
        RhBkgdLabel                matlab.ui.control.Label
        RhSigBkgdSpinner           matlab.ui.control.Spinner
        Cy5BkgdLabel               matlab.ui.control.Label
        Cy5SigBkgdSpinner          matlab.ui.control.Spinner
        ProcessingTab              matlab.ui.container.Tab
        Image                      matlab.ui.control.Image
        zsliderSliderLabel         matlab.ui.control.Label
        zsliderSlider              matlab.ui.control.Slider
        Slider                     matlab.ui.control.Slider
        FFTLabel                   matlab.ui.control.Label
        FFTSmoothSpinnerLabel      matlab.ui.control.Label
        FFTSmoothSpinner           matlab.ui.control.Spinner
        GuassSmoothSpinnerLabel    matlab.ui.control.Label
        GuassSmoothSpinner         matlab.ui.control.Spinner
        FFTxzfactorSpinnerLabel    matlab.ui.control.Label
        FFTxzfactorSpinner         matlab.ui.control.Spinner
        ButtonGroup                matlab.ui.container.ButtonGroup
        GaussOtsuFillFFTButton     matlab.ui.control.RadioButton
        GaussOtsuFillButton        matlab.ui.control.RadioButton
        FFTButton                  matlab.ui.control.RadioButton
        RunButton                  matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        MinVolSliderLabel          matlab.ui.control.Label
        MinVolSlider               matlab.ui.control.Slider
        MinVolEditFieldLabel       matlab.ui.control.Label
        MinVolEditField            matlab.ui.control.NumericEditField
        ViewVolumeButton           matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        wid = 3;                 % width of dilation in outline overlay
        pc;               % percent of Fourier space to keep - smaller numbers -> more blurred out larger images
        pad = 1;                 % pad Fourier space to the next power of 2
        umpx = 0.09;             % image pix size
        umpz = 0.3;              % again in z
        fft_smth;            % smoothing of the eliptical Fourier space mask
        gauss_smth;          % sigma of Guass smooth for Guass, Otsu, Fill, BW
        scl = [1 1 1/0.3];       % scale ratios for volume viewer
        fft_xz_factor;       % factor to increase or decrease the amount of z FFT smoothing compared to xy
        minvol = 0;
        
        % file handling and lists
        rootdir;
        rawfiles;
        jpgfiles;
        probfiles;
        binfiles;
        bindimsfiles;
        nraw;
        njpg;
        nprob;
        nbin;
        nbindims;
        fnames;
        jnames;
        pnames;
        bnames;
        dbnames;
        missing;
        
        % current data to play with
        rimg;
        pimg;
        jimg;
        outimg;
        outimg_edits;
        bw_voxels;
        outimg_CC;
        
        % last set of results struct
        last_s;
        last_min_vol;
        
        % number of channels
        roi_nchns = 2; % number of channels in roi dataset
        sig_nchns = 4; % number of channels in sig dataset
        
        roi_chn = 1; % channel from roi dataset to use for roi
        % here first is assumed to be Rh ctDNA
        sig_chn = 3; % channel from sig dataset to use for signal
        % here if present, second channel is Cy5 ctDNA
        sig_chn2 = 2; % channel from sig dataset to use for signal #2 
        
        sigchns = 1; % number of signal channels to look for
        
        usemask = false;  % boolean for using existing binary masks

    end
    
    methods (Access = private)
        
        function params = fill_params(app)
            params.wid = app.wid;
            params.pc = app.pc;
            params.fft_smth = app.fft_smth;
            params.gauss_smth = app.gauss_smth;
            params.fft_xz_factor = app.fft_xz_factor;
            params.pad = 1;
            params.umpx = app.umpx;
            params.umpz = app.umpz;
            params.scl = app.scl;
            params.minvol = app.minvol;
            assignin('base','param',params);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FFTSmoothSpinner.Value = 1.5;
            app.fft_smth = 1.5;
            app.GuassSmoothSpinner.Value = 1.5;
            app.gauss_smth = 1.5;
            app.FFTxzfactorSpinner.Value = 5;
            app.fft_xz_factor = 5;
            app.Slider.Value = 16.65;
            app.pc = 16.65/100;
            
            app.StatusLabel.Text = '';
            
        end

        % Button pushed function: SetRootButton
        function SetRootButtonPushed(app, event)
            rootpath = 'D:\tmp\Leon';
            app.rootdir = uigetdir(rootpath, 'Select data root folder');
            if app.rootdir == 0
                return
            end
            oldFolder = cd(app.rootdir);
            
            app.rawfiles = dir(fullfile('.\raw', '*.tif'));
            app.nraw = length(app.rawfiles);
            
            app.fnames = {app.rawfiles(:).name};
            for i = 1:length(app.fnames)
                app.fnames{1,i} = app.fnames{1,i}(1:end-4);
            end
            app.FilesListBox.Items = app.fnames;
            
            app.jpgfiles = dir(fullfile('.\nuc', '*_nrmNuc.jpg'));
            app.njpg = length(app.jpgfiles);
            app.jnames = {app.jpgfiles(:).name};
            for i = 1:length(app.jnames)
                app.jnames{1,i} = app.jnames{1,i}(1:end-11);
            end
            
            app.probfiles = dir(fullfile('.\ilastik output', '*_nrmNuc.tiff'));
            app.nprob = length(app.probfiles);
            app.pnames = {app.probfiles(:).name};
            for i = 1:length(app.pnames)
                app.pnames{1,i} = app.pnames{1,i}(1:end-12);
            end
            
            if (max([app.nraw,app.njpg,app.nprob]) ~= min([app.nraw,app.njpg,app.nprob]))
                app.OverviewLabel.FontColor = 'red';
                app.FindMissingFilesButton.Visible = true;
                app.FindMissingFilesButton.Enable = true;
            else
                app.OverviewLabel.FontColor = 'black';
                app.FindMissingFilesButton.Visible = false;
                app.FindMissingFilesButton.Enable = false;
            end

            formatSpec = 'Overview: raw - %d files, jpg - %d files, prob - %d files';
            app.OverviewLabel.Text = sprintf(formatSpec,app.nraw,app.njpg,app.nprob);
            
            assignin('base','rootdir',app.rootdir);
            assignin('base','rawfiles',app.rawfiles);
            assignin('base','fnames',app.fnames);
            assignin('base','jpgfiles',app.jpgfiles);
            assignin('base','jnames',app.jnames);
            assignin('base','probfiles',app.probfiles);
            assignin('base','pnames',app.pnames);
            
            if exist('.\roi')
                % find all sub folders
                % check that a list of bin files exist
                flist = dir('.\roi');
                fldr_flags = [flist.isdir];
                folders = flist(fldr_flags);
                app.UseMasksDropDown.Items = {'none',folders(3:end).name};
                app.UseMasksDropDown.Value = 'none';
            end
            
        end

        % Button pushed function: FindMissingFilesButton
        function FindMissingFilesButtonPushed(app, event)
            app.FilesListBox.Items = {};
            ns = [app.nraw app.njpg app.nprob];
            [M,I] = max(ns);
            switch I
                case 1
                    [C1,ia1] = setdiff(app.fnames, app.jnames);
                    [C2,ia2] = setdiff(app.fnames, app.pnames);
                case 2
                    [C1,ia1] = setdiff(app.jnames, app.fnames);
                    [C2,ia2] = setdiff(app.jnames, app.pnames);
                case 3
                    [C1,ia1] = setdiff(app.pnames, app.fnames);
                    [C2,ia2] = setdiff(app.pnames, app.jnames);
                otherwise
                    %...
            end
            app.missing = [C1; C2];
            app.FilesListBox.Items = app.missing;

        end

        % Button pushed function: OpeninExplorerButton
        function OpeninExplorerButtonPushed(app, event)
            fname = app.FilesListBox.Value;
            if ~isempty(fname)
                % note, the below works for the cmd line, but not in Matlab
                % - see FileBox on change event
                command = ['explorer.exe /select, "' fullfile(app.rootdir, 'raw', [fname{1} '.tif']) '"'];
                status = system(command);
            else
                txt = app.FilesListBoxLabel.Text;
                app.FilesListBoxLabel.Text = 'No file selected...';
                pause(3.3);
                app.FilesListBoxLabel.Text = txt;
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.StatusLabel.Text = 'Running ...';
            drawnow
            % get all slider, spinner, and radio selections
            % use fill_params(app) function to set app params into params
            % struct
            params = fill_params(app);
            %disp(params);
            %disp(app.ButtonGroup.SelectedObject)
            
            switch app.ButtonGroup.SelectedObject
                case app.GaussOtsuFillFFTButton
                    bw_out = smth_otsu_fill_p(app.pimg, params);
                    app.StatusLabel.Text = 'Gauss-Otsu_Fill Finished ...';
                    drawnow
                    lpimg = LPFFT3D_p(bw_out, params);
                    app.StatusLabel.Text = '3D FFT Filter Finished ...';
                    drawnow
                    app.outimg = otsu_bw(lpimg);
                    
                case app.GaussOtsuFillButton
                    app.outimg = smth_otsu_fill_p(app.pimg, params);
                    app.StatusLabel.Text = 'Gauss-Otsu_Fill Finished ...';
                    drawnow
                    
                case app.FFTButton
                    lpimg = LPFFT3D_p(app.pimg, params);
                    app.outimg = otsu_bw(lpimg);
                    app.StatusLabel.Text = '3D FFT Filter Finished ...';
                    drawnow
                    
                otherwise
                    %
            end
            
%             sz = size(app.outimg,3);
%             app.zsliderSlider.Limits = [1 sz];
%             zmid = round(sz/2);
%             app.zsliderSlider.Value = zmid;
            
            assignin('base', 'roi_out_img', app.outimg);
            bw_out = bw_outline_p(app.outimg, params);
            
            dispimg = imoverlay(app.jimg, bw_out, 'cyan');
            app.Image.ImageSource = dispimg;
            
            app.StatusLabel.Text = 'Volume CCs ...'; % CC - connected components
            drawnow
            app.outimg_CC = bwconncomp(app.outimg);
            M = length(app.outimg_CC.PixelIdxList);
            app.bw_voxels = zeros(1,M);
                        
            for i = 1:M
                app.bw_voxels(1,i) = length(app.outimg_CC.PixelIdxList{i});
            end
            app.bw_voxels = sort(app.bw_voxels,'descend');
            max_vox = app.bw_voxels(1);
            min_vox = app.bw_voxels(end);
            
            plot(app.UIAxes, app.bw_voxels, 'b--o');
            
            app.MinVolSlider.Limits = [min_vox max_vox];
            
            app.outimg_edits = app.outimg;
            
            app.StatusLabel.Text = 'Finished ...';
            drawnow
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.pc = value/100;
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            
            if ( strcmp(app.ThumbSwitch.Value,'On') )
                app.ThumbImage.Visible = true;
                app.ThumbImage.Enable = true;
            else
                app.ThumbImage.Visible = false;
                app.ThumbImage.Enable = false;
            end
        
            value = app.FilesListBox.Value;
            
            jpgpath = [app.rootdir '\nuc\' value{1,1} '_nrmNuc.jpg'];
            app.jimg = imread(jpgpath);
            
            app.Image.ImageSource = app.jimg;
            
            if (app.ThumbSwitch.Value == 'On')
                app.ThumbImage.ImageSource = app.jimg;
            end
            
        end

        % Value changed function: FFTSmoothSpinner
        function FFTSmoothSpinnerValueChanged(app, event)
            app.fft_smth = app.FFTSmoothSpinner.Value;
        end

        % Value changed function: GuassSmoothSpinner
        function GuassSmoothSpinnerValueChanged(app, event)
            app.gauss_smth = app.GuassSmoothSpinner.Value;
        end

        % Value changed function: FFTxzfactorSpinner
        function FFTxzfactorSpinnerValueChanged(app, event)
            app.fft_xz_factor = app.FFTxzfactorSpinner.Value;
        end

        % Value changed function: zsliderSlider
        function zsliderSliderValueChanged(app, event)
            value = app.zsliderSlider.Value;
            
            value = uint16(round(value));
            params = fill_params(app);
            imglayer = app.outimg(:,:,value);
            bw_out = bw_outline_p(imglayer, params);
            dispimg = imoverlay(app.jimg, bw_out, 'cyan');
            app.Image.ImageSource = dispimg;
        end

        % Value changed function: ThumbSwitch
        function ThumbSwitchValueChanged(app, event)

            if ( strcmp(app.ThumbSwitch.Value,'On') )
                app.ThumbImage.Visible = true;
                app.ThumbImage.Enable = true;
            else
                app.ThumbImage.Visible = false;
                app.ThumbImage.Enable = false;
            end
        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
            params = fill_params(app);
            
            % setup options for different ROI input sources
            % roi
            % raw_nrm
            % raw
            
            %noe, app.##### variables are being pulled for parfor loop
            root_directory = app.rootdir;
            mvol = app.last_min_vol;
            
            %if app.ROISourceDropDown.Items
            % ToDo setup check prior to makes sure all the files have the
            % same number of channels
            % ToDo add of option to pick which source for roi
            % below 'pf' is parfor
            if app.usemask
                roi_files = app.binfiles;
            else
                roi_files = app.probfiles;
            end
            pf_roi_nchns = app.roi_nchns;
            pf_roi_chn = app.roi_chn;
                
            sig_files = app.rawfiles;
            pf_sig_nchns = app.sig_nchns;
            pf_sig_chn = app.sig_chn;
            pf_sig_chn2 = app.sig_chn2;         
            pf_sigchns = app.sigchns;
            
            sig2_bkgd = app.Cy5SigBkgdSpinner.Value;
            sig_bkgd = app.RhSigBkgdSpinner.Value;
            
            sig_ending = '.tif';
            jpg_ending = '_nrmNuc.jpg';
            roi_ending = '_nrmNuc.tiff';     
                        
            jpg_files = app.jpgfiles;
            
            gaussotsufill = false;
            fft = false;      
            foldername = '';
            switch app.ButtonGroup.SelectedObject
                case app.GaussOtsuFillFFTButton
                    gaussotsufill = true;
                    fft = true;
                    foldername = [foldername sprintf('gof%.1ffft%.1f',params.gauss_smth,params.pc)];
                case app.GaussOtsuFillButton
                    gaussotsufill = true;
                    foldername = [foldername sprintf('gof%.1f',params.gauss_smth)];
                case app.FFTButton
                    fft = true;
                    foldername = [foldername sprintf('fft%.1f',params.pc)];
            end
            
            % create unique bin folder and save current params
            dt = datestr(now,'yyyy-mm-dd_HH.MMAM');
            roi_rel_path = ['roi\' foldername '_' dt];
            mkdir(roi_rel_path);
            spath = fullfile(app.rootdir, [roi_rel_path '\params.mat']);
            save(spath,'params');            
            
            % results structure

            s = [];     
            s = struct('signal_filename',{} ...
                      ,'roi_filename',{} ...
                      ,'cancer_type',{} ...
                      ,'nuc_voxels',{} ...
                      ,'rh_sig_voxels',{} ...
                      ,'rh_sig_int_count',{} ...
                      ,'rh_cyto_sig_voxels',{} ...
                      ,'rh_cyto_sig_int_count',{} ...
                      ,'cy5_sig_voxels',{} ...
                      ,'cy5_sig_int_count',{} ...
                      ,'cy5_cyto_sig_voxels',{} ...
                      ,'cy5_cyto_sig_int_count',{});
            
            N = length(roi_files);
            
            for i = 1:N
                s(i).signal_filename = '';
                s(i).roi_filename = '';
                s(i).cancer_type = '';
                s(i).nuc_voxels = 0;
                s(i).rh_sig_voxels = 0;
                s(i).rh_sig_int_count = 0;
                s(i).rh_cyto_sig_voxels = 0;
                s(i).rh_cyto_sig_int_count = 0;
                s(i).cy5_sig_voxels = 0;
                s(i).cy5_sig_int_count = 0;
                s(i).cy5_cyto_sig_voxels = 0;
                s(i).cy5_cyto_sig_int_count = 0;
            end
            
            
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Processing Files ...');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            
            sig_filelist = {sig_files(:).name};
            sig_filelist = sort(sig_filelist);
            sig_folder = sig_files(1).folder;
            
            roi_filelist = {roi_files(:).name};
            roi_filelist = sort(roi_filelist);
            roi_folder = roi_files(1).folder;
            
            jpg_filelist = {jpg_files(:).name};
            jpg_filelist = sort(jpg_filelist);
            jpg_folder = jpg_files(1).folder;
                
            use_mask = app.usemask;
            
            parfor i = 1:N
    
                %[~,sig_name,~] = fileparts(sig_files(i).name);  % use signal name as root for finding others
                [~,sig_name,~] = fileparts(sig_filelist{i});  % use signal name as root for finding others
                % ToDO - prepare this list of files with checks prior to
                % for loop
                %[~,roi_name,~] = fileparts(roi_files(i).name);
                %[~,roi_name,~] = fileparts(roi_filelist{i});
                %rootname = sig_filelist{i};
                
                s(i).signal_filename = [sig_name sig_ending];
                disp([num2str(i) ': ' s(i).signal_filename]);
                
                s(i).roi_filename = [sig_name roi_ending];
                disp([num2str(i) ': ' s(i).roi_filename]);
                
                %disp(['parfor index: ' num2str(i) ' roi: ' roi_name ' signal: ' sig_name]);
                %[~,jpg_name,~] = fileparts(jpg_files(i).name);
                
                % signal
                fpath = fullfile(sig_folder, [sig_name sig_ending]);
                sig_img = import_tif(fpath, pf_sig_nchns, pf_sig_chn);
                if (pf_sigchns >= 2)
                    sig2_img = import_tif(fpath, pf_sig_nchns, pf_sig_chn2);
                end
                
                % ROI
                if use_mask
                    roi_img = bin_load_mask(roi_folder, sig_name);
                else
                    fpath = fullfile(roi_folder, [sig_name roi_ending]);
                    roi_img = import_tif(fpath, pf_roi_nchns, pf_roi_chn);
                end
                
                fpath = fullfile(jpg_folder, [sig_name jpg_ending]);
                jpg_img = imread(fpath);
                
                roi_sz = size(roi_img,3);
                sig_sz = size(sig_img,3);
                if (roi_sz ~= sig_sz)
                    
                    disp(['parfor index: ' num2str(i) ...
                        ' z size mismatch: ' sig_files(i).name ...
                        ' roi_sz:' num2str(roi_sz) ' sig_sz:' num2str(sig_sz)]);
                    
                    % TODO: add zero entry or error details in collated array
                    continue
                end
                
                if use_mask
                    roi_out_img = roi_img;
                else
                    if gaussotsufill
                        roi_out_img = smth_otsu_fill_p(roi_img, params);
                    else
                        roi_out_img = roi_img;
                    end
                    if fft
                        roi_out_img = LPFFT3D_p(roi_out_img, params);
                    end
                    if ~islogical(roi_out_img)
                        roi_out_img = otsu_bw(roi_out_img);
                    end
                    
                end
                
                % remove CC's (volumes) below min_vol
                CC = bwconncomp(roi_out_img);
                M = length(CC.PixelIdxList);
                for n = 1:M
                    if length(CC.PixelIdxList{n}) < mvol
                        roi_out_img(CC.PixelIdxList{n}) = 0;
                    end
                end
                
                roi_vox_cnt = nnz(roi_out_img);
                s(i).nuc_voxels = roi_vox_cnt;
                roi_cyto_out_img = imcomplement(roi_out_img);
                
                sig_thrs = (sig_img > sig_bkgd) .* sig_img;
                sig_thrs_mskd = sig_thrs .* roi_out_img;
                sig_cyto_thrs_mskd = sig_thrs .* roi_cyto_out_img;
                % total counts above threshold in both regions
                sig_total = sum(sum(sum(sig_thrs_mskd)));
                sig_cyto_total = sum(sum(sum(sig_cyto_thrs_mskd)));
                % volume of sign above threshold in both regions
                sig_voxels = nnz(sig_thrs_mskd);
                sig_cyto_voxels = nnz(sig_cyto_thrs_mskd);

                s(i).rh_sig_voxels = sig_voxels;  
                s(i).rh_sig_int_count = sig_total;
                s(i).rh_cyto_sig_voxels = sig_cyto_voxels;  
                s(i).rh_cyto_sig_int_count = sig_cyto_total;
                
                if (pf_sigchns >= 2)
                    sig2_thrs = (sig2_img > sig2_bkgd) .* sig2_img;
                    sig2_thrs_mskd = sig2_thrs .* roi_out_img;
                    sig2_cyto_thrs_mskd = sig2_thrs .* roi_cyto_out_img;
                    % total counts above threshold in both regions
                    sig2_total = sum(sum(sum(sig2_thrs_mskd)));
                    sig2_cyto_total = sum(sum(sum(sig2_cyto_thrs_mskd)));
                    % volume of sig2n above threshold in both regions
                    sig2_voxels = nnz(sig2_thrs_mskd);
                    sig2_cyto_voxels = nnz(sig2_cyto_thrs_mskd);
    
                    s(i).cy5_sig_voxels = sig2_voxels;  
                    s(i).cy5_sig_int_count = sig2_total;
                    s(i).cy5_cyto_sig_voxels = sig2_cyto_voxels;  
                    s(i).cy5_cyto_sig_int_count = sig2_cyto_total;
                end


                if ~use_mask
                    % write binary roi image data - uint8
                    roi_bin_path = fullfile(root_directory, roi_rel_path, [sig_name '.bin']);
                    fid = fopen(roi_bin_path,'w');
                    fwrite(fid,roi_out_img(:),'uint8');
                    fclose(fid);
                    % write roi image size variable
                    roi_img_size = size(roi_out_img);
                    roi_size_path = fullfile(root_directory, roi_rel_path, [sig_name '_dims.bin']);
                    fid = fopen(roi_size_path,'w');
                    fwrite(fid,roi_img_size(:), 'uint16');
                    fclose(fid);
                end
                % overlay outline on jpg here
                bw_out = bw_outline_p(roi_out_img, params);
                bw_sig = bw_outline_p(sig_thrs_mskd, params);
                ovly_jpg = imoverlay(jpg_img, bw_out, 'cyan');
                ovly_jpg = imoverlay(ovly_jpg, bw_sig, 'white');
                roi_jpg_path = fullfile(root_directory, roi_rel_path, [sig_name '.jpg']);
                imwrite(ovly_jpg,roi_jpg_path);
                    
                send(D,i);
            end


            waitbar(1, h, 'Finishing up ...');
            % ToDo - if reuse of bin files, redesign the output results
            % struct save below
            roi_res_path = fullfile(root_directory, 'roi', [dt '_results.mat']);
            save(roi_res_path,'s');
            
            sT = process_ctDNA_table(s);
            prts = regexp(root_directory,'\','split');
            rootlast = prts{1,end};
            roi_res_table_path = fullfile(root_directory, 'roi', [rootlast '_' dt '_res.xlsx']);
            writetable(sT,roi_res_table_path);
            if use_mask
                roi_bin_path = fullfile(root_directory, roi_rel_path, [dt '_binfolder.txt']);
                fid = fopen(roi_bin_path,'w');
                roi_folder = replace(roi_folder, '\', '\\');
                fprintf(fid,roi_folder);
                fclose(fid); 
            end
            delete(h);
            
                function nUpdateWaitbar(~)
                    waitbar(p/N, h);
                    p = p + 1;
                end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.ProcessingTab
                value = app.FilesListBox.Value;
                if ~isempty(value)
                    rawpath = [app.rootdir '\raw\' value{1,1} '.tif'];
                    app.rimg = import_tif(rawpath, app.sig_nchns, app.sig_chn);
                    
                    probpath = [app.rootdir '\ilastik output\' value{1,1} '_nrmNuc.tiff'];
                    app.pimg = import_tif(probpath, app.roi_nchns, app.roi_chn);
                end
            end           
        end

        % Value changed function: MinVolSlider
        function MinVolSliderValueChanged(app, event)
            value = app.MinVolSlider.Value;
            app.last_min_vol = value;
            app.minvol = value;
            app.MinVolEditField.Value = value;
            
            app.outimg_edits = app.outimg;
            for i = 1:length(app.outimg_CC.PixelIdxList)
                if length(app.outimg_CC.PixelIdxList{i}) < value
                    app.outimg_edits(app.outimg_CC.PixelIdxList{i}) = 0;
                end
            end
            
            params.wid = 3;
            bw_out = bw_outline_p(app.outimg_edits, params);
            dispimg = imoverlay(app.jimg, bw_out, 'cyan');
            app.Image.ImageSource = dispimg;
            
            plot(app.UIAxes, app.bw_voxels, 'b--o');
            line(app.UIAxes, app.UIAxes.XLim, [value,value],'Color','#7E2F8E','LineStyle','--');
        end

        % Value changed function: MinVolEditField
        function MinVolEditFieldValueChanged(app, event)
            value = app.MinVolEditField.Value;
            if app.MinVolSlider.Limits(1) > value
                app.MinVolSlider.Limits = [value app.MinVolSlider.Limits(2)];
            end
            if app.MinVolSlider.Limits(2) < value
                app.MinVolSlider.Limits = [app.MinVolSlider.Limits(1) value];
            end
            app.MinVolSlider.Value = value;
            app.minvol = value;
        end

        % Button pushed function: ViewVolumeButton
        function ViewVolumeButtonPushed(app, event)
            volumeViewer(app.outimg_edits,'ScaleFactors',app.scl);
            
        end

        % Value changed function: SigNumChnsSpinner
        function SigNumChnsSpinnerValueChanged(app, event)
            value = app.SigNumChnsSpinner.Value;
            app.sig_nchns = value;
        end

        % Value changed function: RhSigChnSpinner
        function RhSigChnSpinnerValueChanged(app, event)
            value = app.RhSigChnSpinner.Value;
            app.sig_chn = value;
        end

        % Value changed function: Cy5SigChnSpinner
        function Cy5SigChnSpinnerValueChanged(app, event)
            value = app.Cy5SigChnSpinner.Value;
            app.sig_chn2 = value;
        end

        % Value changed function: ROINumChnsSpinner
        function ROINumChnsSpinnerValueChanged(app, event)
            value = app.ROINumChnsSpinner.Value;
            app.roi_nchns = value;
        end

        % Value changed function: ROIChnSpinner
        function ROIChnSpinnerValueChanged(app, event)
            value = app.ROIChnSpinner.Value;
            app.roi_chn = value;
        end

        % Selection changed function: SignalChannelsButtonGroup
        function SignalChannelsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SignalChannelsButtonGroup.SelectedObject;
            switch selectedButton
                case app.SingleChannelButton
                    app.sigchns = 1;
                case app.DoubleChannelButton
                    app.sigchns = 2;                
                    %disp(app.sigchns);
            end
        end

        % Value changed function: UseMasksDropDown
        function UseMasksDropDownValueChanged(app, event)
            value = app.UseMasksDropDown.Value;
            if strcmp(value,'none')
                app.usemask = false;
                app.maskstatusLabel.Text = 'no mask selected';
                app.maskstatusLabel.FontColor = 'black';
            else
    
                fpath = fullfile(app.rootdir, 'roi', value);
                if exist(fpath)
                    app.binfiles = dir(fullfile(fpath, '*.bin'));
                    app.bindimsfiles = dir(fullfile(fpath, '*_dims.bin'));
                    rem_idxs = [];
                    for i = 1:length(app.binfiles)
                        if strcmp(app.binfiles(i).name(end-8:end), '_dims.bin')
                            rem_idxs = [rem_idxs i];
                        end
                    end
                    app.binfiles(rem_idxs) = [];
                    
                    app.nbin = length(app.binfiles);
                    app.nbindims = length(app.bindimsfiles);
                    app.dbnames = {app.bindimsfiles(:).name};
                    app.bnames = {app.binfiles(:).name};
    
                    for i = 1:length(app.bnames)
                        app.bnames{1,i} = app.bnames{1,i}(1:end-4);
                    end
                    for i = 1:length(app.dbnames)
                        app.dbnames{1,i} = app.dbnames{1,i}(1:end-9);
                    end
                end
                
                if (max([app.nbin,app.nbindims,app.nraw]) ~= min([app.nbin,app.nbindims,app.nraw]))
                    app.maskstatusLabel.FontColor = 'red';
                    app.usemask = false;
                else
                    app.maskstatusLabel.FontColor = 'black';
                    app.usemask = true;
                end
                formatSpec = 'raw - %d files, bin - %d files, bindims - %d files';
                app.maskstatusLabel.Text = sprintf(formatSpec,app.nraw,app.nbin,app.nbindims);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {844, 844};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {964, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.FilesListBoxLabel = uilabel(app.FilesTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.FilesTab);
            app.FilesListBox.Items = {};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.SetRootButton = uibutton(app.FilesTab, 'push');
            app.SetRootButton.ButtonPushedFcn = createCallbackFcn(app, @SetRootButtonPushed, true);
            app.SetRootButton.Text = 'Set Root';
            app.OverviewLabel = uilabel(app.FilesTab);
            app.OverviewLabel.Text = 'Overview:';
            app.FindMissingFilesButton = uibutton(app.FilesTab, 'push');
            app.FindMissingFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FindMissingFilesButtonPushed, true);
            app.FindMissingFilesButton.Enable = 'off';
            app.FindMissingFilesButton.Visible = 'off';
            app.FindMissingFilesButton.Text = 'Find Missing Files';
            app.OpeninExplorerButton = uibutton(app.FilesTab, 'push');
            app.OpeninExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninExplorerButtonPushed, true);
            app.OpeninExplorerButton.Text = 'Open in Explorer';
            app.ThumbSwitchLabel = uilabel(app.FilesTab);
            app.ThumbSwitchLabel.Text = 'Thumb';
            app.ThumbSwitch = uiswitch(app.FilesTab, 'rocker');
            app.ThumbSwitch.ValueChangedFcn = createCallbackFcn(app, @ThumbSwitchValueChanged, true);
            app.ThumbSwitch.Value = 'On';
            app.ThumbImage = uiimage(app.FilesTab);
            app.ThumbImage.Enable = 'off';
            app.ThumbImage.Visible = 'off';
            app.ProcessAllButton = uibutton(app.FilesTab, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Process All';
            app.ROISourceDropDownLabel = uilabel(app.FilesTab);
            app.ROISourceDropDownLabel.Enable = 'off';
            app.ROISourceDropDownLabel.Text = 'ROI Source';
            app.ROISourceDropDown = uidropdown(app.FilesTab);
            app.ROISourceDropDown.Items = {'ilastik Nuc Prob. Map', 'Raw Data', 'Normalized Raw Data'};
            app.ROISourceDropDown.Enable = 'off';
            app.ROISourceDropDown.Value = 'ilastik Nuc Prob. Map';
            app.SignalChannelsButtonGroup = uibuttongroup(app.FilesTab);
            app.SignalChannelsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignalChannelsButtonGroupSelectionChanged, true);
            app.SignalChannelsButtonGroup.Title = 'Signal Channels';
            app.SingleChannelButton = uiradiobutton(app.SignalChannelsButtonGroup);
            app.SingleChannelButton.Text = 'Single Channel';
            app.SingleChannelButton.Value = true;
            app.DoubleChannelButton = uiradiobutton(app.SignalChannelsButtonGroup);
            app.DoubleChannelButton.Text = 'Double Channel';
            app.TripleChannelButton = uiradiobutton(app.SignalChannelsButtonGroup);
            app.TripleChannelButton.Enable = 'off';
            app.TripleChannelButton.Text = 'Triple Channel';
            app.RhSigChnSpinnerLabel = uilabel(app.FilesTab);
            app.RhSigChnSpinnerLabel.Text = 'Rh Sig Chn';
            app.RhSigChnSpinner = uispinner(app.FilesTab);
            app.RhSigChnSpinner.Limits = [1 4];
            app.RhSigChnSpinner.ValueChangedFcn = createCallbackFcn(app, @RhSigChnSpinnerValueChanged, true);
            app.RhSigChnSpinner.Value = 3;
            app.Cy5SigChnSpinnerLabel = uilabel(app.FilesTab);
            app.Cy5SigChnSpinnerLabel.Text = 'Cy5 Sig Chn';
            app.Cy5SigChnSpinner = uispinner(app.FilesTab);
            app.Cy5SigChnSpinner.Limits = [1 4];
            app.Cy5SigChnSpinner.ValueChangedFcn = createCallbackFcn(app, @Cy5SigChnSpinnerValueChanged, true);
            app.Cy5SigChnSpinner.Value = 2;
            app.SigNumChnsSpinnerLabel = uilabel(app.FilesTab);
            app.SigNumChnsSpinnerLabel.Text = 'Sig Num Chns';
            app.SigNumChnsSpinner = uispinner(app.FilesTab);
            app.SigNumChnsSpinner.Limits = [1 4];
            app.SigNumChnsSpinner.ValueChangedFcn = createCallbackFcn(app, @SigNumChnsSpinnerValueChanged, true);
            app.SigNumChnsSpinner.Value = 4;
            app.ROINumChnsSpinnerLabel = uilabel(app.FilesTab);
            app.ROINumChnsSpinnerLabel.Text = 'ROI Num Chns';
            app.ROINumChnsSpinner = uispinner(app.FilesTab);
            app.ROINumChnsSpinner.Limits = [1 4];
            app.ROINumChnsSpinner.ValueChangedFcn = createCallbackFcn(app, @ROINumChnsSpinnerValueChanged, true);
            app.ROINumChnsSpinner.Value = 2;
            app.ROIChnSpinnerLabel = uilabel(app.FilesTab);
            app.ROIChnSpinnerLabel.Text = 'ROI Chn';
            app.ROIChnSpinner = uispinner(app.FilesTab);
            app.ROIChnSpinner.Limits = [1 4];
            app.ROIChnSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIChnSpinnerValueChanged, true);
            app.ROIChnSpinner.Value = 1;
            app.UseMasksDropDownLabel = uilabel(app.FilesTab);
            app.UseMasksDropDownLabel.Text = 'Use Masks';
            app.UseMasksDropDown = uidropdown(app.FilesTab);
            app.UseMasksDropDown.Items = {'None'};
            app.UseMasksDropDown.ValueChangedFcn = createCallbackFcn(app, @UseMasksDropDownValueChanged, true);
            app.UseMasksDropDown.Value = 'None';
            app.maskstatusLabel = uilabel(app.FilesTab);
            app.maskstatusLabel.Text = 'mask status: ok';
            app.RhBkgdLabel = uilabel(app.FilesTab);
            app.RhBkgdLabel.Text = 'Rh Bkgd';
            app.RhSigBkgdSpinner = uispinner(app.FilesTab);
            app.RhSigBkgdSpinner.Limits = [0 255];
            app.RhSigBkgdSpinner.Value = 4;
            app.Cy5BkgdLabel = uilabel(app.FilesTab);
            app.Cy5BkgdLabel.Text = 'Cy5 Bkgd';
            app.Cy5SigBkgdSpinner = uispinner(app.FilesTab);
            app.Cy5SigBkgdSpinner.Limits = [0 255];
            app.Cy5SigBkgdSpinner.Value = 4;
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.Image = uiimage(app.ProcessingTab);
            app.zsliderSliderLabel = uilabel(app.ProcessingTab);
            app.zsliderSliderLabel.Enable = 'off';
            app.zsliderSliderLabel.Visible = 'off';
            app.zsliderSliderLabel.Text = 'z-slider';
            app.zsliderSlider = uislider(app.ProcessingTab);
            app.zsliderSlider.Limits = [1 100];
            app.zsliderSlider.ValueChangedFcn = createCallbackFcn(app, @zsliderSliderValueChanged, true);
            app.zsliderSlider.Enable = 'off';
            app.zsliderSlider.Visible = 'off';
            app.zsliderSlider.Value = 1;
            app.Slider = uislider(app.ProcessingTab);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.FFTLabel = uilabel(app.ProcessingTab);
            app.FFTLabel.Text = 'FFT %';
            app.FFTSmoothSpinnerLabel = uilabel(app.ProcessingTab);
            app.FFTSmoothSpinnerLabel.Text = 'FFT Smooth';
            app.FFTSmoothSpinner = uispinner(app.ProcessingTab);
            app.FFTSmoothSpinner.Step = 0.5;
            app.FFTSmoothSpinner.Limits = [0 20];
            app.FFTSmoothSpinner.ValueChangedFcn = createCallbackFcn(app, @FFTSmoothSpinnerValueChanged, true);
            app.GuassSmoothSpinnerLabel = uilabel(app.ProcessingTab);
            app.GuassSmoothSpinnerLabel.Text = 'Guass Smooth';
            app.GuassSmoothSpinner = uispinner(app.ProcessingTab);
            app.GuassSmoothSpinner.Step = 0.5;
            app.GuassSmoothSpinner.Limits = [0 20];
            app.GuassSmoothSpinner.ValueChangedFcn = createCallbackFcn(app, @GuassSmoothSpinnerValueChanged, true);
            app.FFTxzfactorSpinnerLabel = uilabel(app.ProcessingTab);
            app.FFTxzfactorSpinnerLabel.Text = 'FFT xz factor';
            app.FFTxzfactorSpinner = uispinner(app.ProcessingTab);
            app.FFTxzfactorSpinner.Step = 0.2;
            app.FFTxzfactorSpinner.Limits = [0 10];
            app.FFTxzfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @FFTxzfactorSpinnerValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.ProcessingTab);
            app.ButtonGroup.Title = 'Button Group';
            app.GaussOtsuFillFFTButton = uiradiobutton(app.ButtonGroup);
            app.GaussOtsuFillFFTButton.Text = 'Gauss - Otsu - Fill - FFT';
            app.GaussOtsuFillFFTButton.Value = true;
            app.GaussOtsuFillButton = uiradiobutton(app.ButtonGroup);
            app.GaussOtsuFillButton.Text = 'Gauss - Otsu - Fill';
            app.FFTButton = uiradiobutton(app.ButtonGroup);
            app.FFTButton.Text = 'FFT';
            app.RunButton = uibutton(app.ProcessingTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StatusLabel = uilabel(app.ProcessingTab);
            app.StatusLabel.Text = 'Status';
            app.UIAxes = uiaxes(app.ProcessingTab);
            title(app.UIAxes, 'Voxel Sizes')
            xlabel(app.UIAxes, 'Decending Vol')
            ylabel(app.UIAxes, 'Voxel Count')
            app.UIAxes.YScale = 'log';
            app.MinVolSliderLabel = uilabel(app.ProcessingTab);
            app.MinVolSliderLabel.Text = 'Min Vol';
            app.MinVolSlider = uislider(app.ProcessingTab);
            app.MinVolSlider.ValueChangedFcn = createCallbackFcn(app, @MinVolSliderValueChanged, true);
            app.MinVolEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinVolEditFieldLabel.Text = 'Min Vol';
            app.MinVolEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MinVolEditField.Limits = [0 Inf];
            app.MinVolEditField.ValueChangedFcn = createCallbackFcn(app, @MinVolEditFieldValueChanged, true);
            app.ViewVolumeButton = uibutton(app.ProcessingTab, 'push');
            app.ViewVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewVolumeButtonPushed, true);
            app.ViewVolumeButton.Text = 'View Volume';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = img_test_2019

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end